classdef passive_filter_circuit_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        OButton                       matlab.ui.control.Button
        IButton                       matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        PhaseSpectrumButton           matlab.ui.control.Button
        MagnitudeSpectrumButton       matlab.ui.control.Button
        MagnitudeofGainButton         matlab.ui.control.Button
        InputSignalButton             matlab.ui.control.Button
        OutputSignalButton            matlab.ui.control.Button
        PhaseofGainButton             matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
        FLabel_3                      matlab.ui.control.Label
        mHLabel_3                     matlab.ui.control.Label
        mHLabel_2                     matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        FLabel_2                      matlab.ui.control.Label
        mHLabel                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        FLabel                        matlab.ui.control.Label
        SmoothingFactorLabel          matlab.ui.control.Label
        SmothingFactorEditField       matlab.ui.control.NumericEditField
        InputButton                   matlab.ui.control.Button
        EndingTimeEditFieldLabel      matlab.ui.control.Label
        EndingTimeEditField           matlab.ui.control.NumericEditField
        InputSignalftEditField        matlab.ui.control.EditField
        InputSignalftEditFieldLabel   matlab.ui.control.Label
        AnalyzeButton_2               matlab.ui.control.Button
        CapacitanceFLabel             matlab.ui.control.Label
        QualityFactorEditField        matlab.ui.control.NumericEditField
        QualityFactorEditFieldLabel   matlab.ui.control.Label
        BandwidthHzEditField          matlab.ui.control.NumericEditField
        BandwidthHzEditFieldLabel     matlab.ui.control.Label
        CentreFrequencyHzEditField    matlab.ui.control.NumericEditField
        CentreFrequencyHzEditFieldLabel  matlab.ui.control.Label
        HigherCutoffFrequencyHzEditField_2  matlab.ui.control.NumericEditField
        HigherCutoffFrequencyHzEditField_2Label  matlab.ui.control.Label
        LowerCutoffFrequencyHzEditField  matlab.ui.control.NumericEditField
        LowerCutoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        CapacitanceFEditField_2       matlab.ui.control.NumericEditField
        InductancemHEditField         matlab.ui.control.NumericEditField
        InductancemHEditFieldLabel    matlab.ui.control.Label
        CutoffFrequencyHzEditField    matlab.ui.control.NumericEditField
        CutoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        AnalyzeButton                 matlab.ui.control.Button
        ResistanceEditField           matlab.ui.control.NumericEditField
        ResistanceLabel               matlab.ui.control.Label
        CapacitanceFEditField         matlab.ui.control.NumericEditField
        CapacitanceFEditFieldLabel    matlab.ui.control.Label
        PlotLabel                     matlab.ui.control.Label
        EditField_6                   matlab.ui.control.NumericEditField
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        Image7                        matlab.ui.control.Image
        Image6                        matlab.ui.control.Image
        CircuitDiagramLabel_2         matlab.ui.control.Label
        DesignButton_2                matlab.ui.control.Button
        HigherCutoffFrequencyHzEditField  matlab.ui.control.NumericEditField
        HigherCutoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        LowerCutoffFrequencyHzEditField_2  matlab.ui.control.NumericEditField
        LowerCutoffFrequencyHzEditField_2Label  matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        DesignButton                  matlab.ui.control.Button
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        CircuitDiagramLabel           matlab.ui.control.Label
        CutoffFrequencyHzEditField_2  matlab.ui.control.NumericEditField
        CutoffFrequencyHzEditField_2Label  matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        BandStopRLCButton             matlab.ui.control.Button
        BandPassRLCButton             matlab.ui.control.Button
        HighPassRLButton              matlab.ui.control.Button
        HighPassRCButton              matlab.ui.control.Button
        LowPassRLButton               matlab.ui.control.Button
        LowPassRCButton               matlab.ui.control.Button
        AnalyzeFilterCircuitButton    matlab.ui.control.Button
        DesignFilterCircuitButton     matlab.ui.control.Button
        DevelopersNaeemAlveeLabel     matlab.ui.control.Label
        PassiveFilterCircuitAnalyzerLabel  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        page = NaN; % Description
        te = NaN;
        k = NaN;
        convolution2 = NaN;
        inputexpression = NaN;
        spectrum = NaN;
    end
    
    methods (Access = private)
        
        function inputvisible(app)
            app.InputSignalftEditField.Visible = 'on';
            app.InputSignalftEditFieldLabel.Visible = 'on';
            app.SmothingFactorEditField.Visible = 'on';
            app.SmoothingFactorLabel.Visible = 'on';
            app.EndingTimeEditField.Visible = 'on';
            app.EndingTimeEditFieldLabel.Visible = 'on';
        end
        
        function inputinvisible(app)
            app.InputSignalftEditField.Visible = 'off';
            app.InputSignalftEditFieldLabel.Visible = 'off';
            app.SmothingFactorEditField.Visible = 'off';
            app.SmoothingFactorLabel.Visible = 'off';
            app.EndingTimeEditField.Visible = 'off';
            app.EndingTimeEditFieldLabel.Visible = 'off';
            app.SmothingFactorEditField.Value = 0;
        end
        
        function input = Input(app,t)
            xt = str2func(['@(t)',app.inputexpression]);
            input = xt(t);
        end
        
        function  output = Convolution(app,step_response,input)
            if app.convolution2 == 1
                output = conv(step_response*(app.te/(1000*app.k)),input);
                input(2*length(input)-1) = 0;
                app.convolution2 = 0;
                output = input - output;
            else
                output = conv(step_response*(app.te/(1000*app.k)),input);
            end
        end
        
        function  step_response = Step_Response(app,t)
            if app.page == 111
                C = 47E-6;
                R = app.EditField.Value;
                ht = @(t) (1/(R*C))*exp(-t/(R*C));
                step_response = ht(t);
            elseif app.page == 121
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                C = C*1E-6;
                ht = @(t) (1/(R*C))*exp(-t/(R*C));
                step_response = ht(t);
            elseif app.page == 112
                L = 25E-3;
                R = app.EditField_2.Value;
                ht = @(t) (R/L)*exp(-t*R/L);
                step_response = ht(t);
            elseif app.page == 122
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                L = L*1E-3;
                ht = @(t) (R/L)*exp(-t*R/L);
                step_response = ht(t);
            elseif app.page == 113
                C = 47E-6;
                R = app.EditField_3.Value;
                ht = @(t) (1/(R*C))*exp(-t/(R*C));
                app.convolution2 = 1;
                step_response = ht(t);
            elseif app.page == 123
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                C = C*1E-6;
                ht = @(t) (1/(R*C))*exp(-t/(R*C));
                app.convolution2 = 1;
                step_response = ht(t);
            elseif app.page == 114
                L = 25E-3;
                R = app.EditField.Value;
                ht = @(t) (R/L)*exp(-t*R/L);
                app.convolution2 = 1;
                step_response = ht(t);
            elseif app.page == 124
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                L = L*1E-3;
                ht = @(t) (R/L)*exp(-t*R/L);
                app.convolution2 = 1;
                step_response = ht(t);
            elseif app.page == 115
                L = app.EditField_4.Value;
                L = L*1E-3;
                C = 47*1E-6;
                R = app.EditField_6.Value;
                a = R/(2*L);
                b = 1/(sqrt(L*C));
                if a > b
                    p1 = -a+sqrt(a^2-b^2);
                    p2 = -a-sqrt(a^2-b^2);
                    ht = @(t) (2*a/(p1-p2))*(p1*exp(p1*t)-p2*exp(p2*t));
                elseif a < b
                    q = sqrt(b^2-a^2);
                    ht = @(t) (2*a*cos(q*t)-((2*a^2)/q)*sin(q*t)).*exp(-a*t);
                else
                    ht = @(t) 2*a(1+a*t).*exp(a*t);
                end
                step_response = ht(t);
            elseif app.page == 125
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                L = L*1E-3;
                C = C*1E-6;
                a = R/(2*L);
                b = 1/(sqrt(L*C));
                if a > b
                    p1 = -a+sqrt(a^2-b^2);
                    p2 = -a-sqrt(a^2-b^2);
                    ht = @(t) (2*a/(p1-p2))*(p1*exp(p1*t)-p2*exp(p2*t));
                elseif a < b
                    q = sqrt(b^2-a^2);
                    ht = @(t) (2*a*cos(q*t)-((2*a^2)/q)*sin(q*t)).*exp(-a*t);
                else
                    ht = @(t) 2*a(1+a*t).*exp(a*t);
                end
                step_response = ht(t);
            elseif app.page == 116
                L = app.EditField_4.Value;
                L = L*1E-3;
                C = 47*1E-6;
                R = app.EditField_6.Value;
                a = R/(2*L);
                b = 1/(sqrt(L*C));
                if a > b
                    p1 = -a+sqrt(a^2-b^2);
                    p2 = -a-sqrt(a^2-b^2);
                    ht = @(t) (2*a/(p1-p2))*(p1*exp(p1*t)-p2*exp(p2*t));
                elseif a < b
                    q = sqrt(b^2-a^2);
                    ht = @(t) (2*a*cos(q*t)-((2*a^2)/q)*sin(q*t)).*exp(-a*t);
                else
                    ht = @(t) 2*a(1+a*t).*exp(a*t);
                end
                app.convolution2 = 1;
                step_response = ht(t);
            elseif app.page == 126
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                L = L*1E-3;
                C = C*1E-6;
                a = R/(2*L);
                b = 1/(sqrt(L*C));
                if a > b
                    p1 = -a+sqrt(a^2-b^2);
                    p2 = -a-sqrt(a^2-b^2);
                    ht = @(t) (2*a/(p1-p2))*(p1*exp(p1*t)-p2*exp(p2*t));
                elseif a < b
                    q = sqrt(b^2-a^2);
                    ht = @(t) (2*a*cos(q*t)-((2*a^2)/q)*sin(q*t)).*exp(-a*t);
                else
                    ht = @(t) 2*a(1+a*t).*exp(a*t);
                end
                app.convolution2 = 1;
                step_response = ht(t);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.page = 0;
            app.te = 0;
            app.k = 1;
            app.convolution2 = 0;
            app.spectrum = 0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Visible = 'off';
            app.AnalyzeFilterCircuitButton.Visible = 'on';
            app.DesignFilterCircuitButton.Visible = 'on';
            app.BackButton.Visible = 'on';
            app.page = 1;
        end

        % Button pushed function: AnalyzeFilterCircuitButton
        function AnalyzeFilterCircuitButtonPushed(app, event)
            app.AnalyzeFilterCircuitButton.Visible = 'off';
            app.DesignFilterCircuitButton.Visible = 'off';
            app.LowPassRCButton.Visible = 'on';
            app.LowPassRLButton.Visible = 'on';
            app.HighPassRCButton.Visible = 'on';
            app.HighPassRLButton.Visible = 'on';
            app.BandPassRLCButton.Visible = 'on';
            app.BandStopRLCButton.Visible = 'on';
            app.page = 12;
        end

        % Button pushed function: DesignFilterCircuitButton
        function DesignFilterCircuitButtonPushed(app, event)
            app.AnalyzeFilterCircuitButton.Visible = 'off';
            app.DesignFilterCircuitButton.Visible = 'off';
            app.LowPassRCButton.Visible = 'on';
            app.LowPassRLButton.Visible = 'on';
            app.HighPassRCButton.Visible = 'on';
            app.HighPassRLButton.Visible = 'on';
            app.BandPassRLCButton.Visible = 'on';
            app.BandStopRLCButton.Visible = 'on';
            app.page = 11;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if app.page == 1
                app.StartButton.Visible = 'on';
                app.AnalyzeFilterCircuitButton.Visible = 'off';
                app.DesignFilterCircuitButton.Visible = 'off';
                app.BackButton.Visible = 'off';
                app.page = 0;
            elseif app.page == 11
                app.AnalyzeFilterCircuitButton.Visible = 'on';
                app.DesignFilterCircuitButton.Visible = 'on';
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.page = 1;
            elseif app.page == 12
                app.AnalyzeFilterCircuitButton.Visible = 'on';
                app.DesignFilterCircuitButton.Visible = 'on';
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.page = 1;
            elseif app.page == 111
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image2.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.CutoffFrequencyHzEditField_2.Visible = 'off';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.DesignButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Value = 0;
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.FLabel.Visible = 'off';
                app.Label.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 11;
            elseif app.page == 121
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image2.Visible = 'off';
                app.CutoffFrequencyHzEditField.Visible = 'off';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.CapacitanceFEditField.Value = 0;
                app.ResistanceEditField.Value = 0;
                app.ResistanceEditField.Visible = 'off';
                app.ResistanceLabel.Visible = 'off';
                app.CapacitanceFEditField.Visible = 'off';
                app.CapacitanceFEditFieldLabel.Visible = 'off';
                app.AnalyzeButton.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.FLabel.Visible = 'off';
                app.Label.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 12;
            elseif app.page == 112
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image3.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.CutoffFrequencyHzEditField_2.Visible = 'off';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.mHLabel.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.DesignButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Value = 0;
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';  
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 11; 
            elseif app.page == 122
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image3.Visible = 'off';
                app.CutoffFrequencyHzEditField.Visible = 'off';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.InductancemHEditField.Value = 0;
                app.ResistanceEditField.Value = 0;
                app.ResistanceEditField.Visible = 'off';
                app.ResistanceLabel.Visible = 'off';
                app.InductancemHEditField.Visible = 'off';
                app.mHLabel.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.InductancemHEditFieldLabel.Visible = 'off';
                app.AnalyzeButton.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 12;
            elseif app.page == 113
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image4.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_3.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.CutoffFrequencyHzEditField_2.Visible = 'off';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.FLabel_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.DesignButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Value = 0;
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';  
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 11;
            elseif app.page == 123
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image4.Visible = 'off';
                app.CutoffFrequencyHzEditField.Visible = 'off';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_3.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.CapacitanceFEditField.Value = 0;
                app.ResistanceEditField.Value = 0;
                app.ResistanceEditField.Visible = 'off';
                app.ResistanceLabel.Visible = 'off';
                app.CapacitanceFEditField.Visible = 'off';
                app.FLabel_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.CapacitanceFEditFieldLabel.Visible = 'off';
                app.AnalyzeButton.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 12;
            elseif app.page == 114
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image5.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.CutoffFrequencyHzEditField_2.Visible = 'off';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.Label.Visible ='off';
                app.mHLabel_2.Visible = 'off';
                app.DesignButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Value = 0;
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';   
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 11;
            elseif app.page == 124
                app.CircuitDiagramLabel.Visible = 'off';
                app.Image5.Visible = 'off';
                app.CutoffFrequencyHzEditField.Visible = 'off';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.InductancemHEditField.Value = 0;
                app.ResistanceEditField.Value = 0;
                app.ResistanceEditField.Visible = 'off';
                app.ResistanceLabel.Visible = 'off';
                app.InductancemHEditField.Visible = 'off';
                app.InductancemHEditFieldLabel.Visible = 'off';
                app.Label.Visible ='off';
                app.mHLabel_2.Visible = 'off';
                app.AnalyzeButton.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 12;
            elseif app.page == 115
                app.EditField_4.Visible = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.CircuitDiagramLabel_2.Visible = 'off';
                app.Image6.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField_2.Value = 0;
                app.HigherCutoffFrequencyHzEditField.Value = 0;
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.LowerCutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.HigherCutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.HigherCutoffFrequencyHzEditField.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField_2.Visible = 'off';
                app.mHLabel_3.Visible = 'off';
                app.FLabel_3.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.DesignButton_2.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';   
                app.InputSignalftEditField.Value = '';
                app.CentreFrequencyHzEditField.Visible = 'off';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'off';
                app.BandwidthHzEditField.Visible = 'off';
                app.BandwidthHzEditFieldLabel.Visible = 'off';
                app.QualityFactorEditField.Visible = 'off';
                app.QualityFactorEditFieldLabel.Visible = 'off';
                app.EndingTimeEditField.Value = 0;
                app.page = 11;
            elseif app.page == 125
                app.EditField_4.Visible = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.CircuitDiagramLabel_2.Visible = 'off';
                app.Image6.Visible = 'off';
                app.ResistanceEditField.Value = 0;
                app.InductancemHEditField.Value = 0;
                app.CapacitanceFEditField_2.Value = 0;
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.ResistanceEditField.Visible = 'off';
                app.ResistanceLabel.Visible = 'off';
                app.InductancemHEditField.Visible = 'off';
                app.InductancemHEditFieldLabel.Visible = 'off';
                app.CapacitanceFEditField_2.Visible = 'off';
                app.CapacitanceFLabel.Visible = 'off';
                app.mHLabel_3.Visible = 'off';
                app.FLabel_3.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField.Visible = 'off';
                app.LowerCutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.HigherCutoffFrequencyHzEditField_2.Visible = 'off';
                app.HigherCutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.CentreFrequencyHzEditField.Visible = 'off';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'off';
                app.BandwidthHzEditField.Visible = 'off';
                app.BandwidthHzEditFieldLabel.Visible = 'off';
                app.QualityFactorEditField.Visible = 'off';
                app.QualityFactorEditFieldLabel.Visible = 'off';
                app.AnalyzeButton_2.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 12;
            elseif app.page == 116
                app.mHLabel_3.Visible = 'off';
                app.FLabel_3.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.EditField_4.Visible = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.CircuitDiagramLabel_2.Visible = 'off';
                app.Image7.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField_2.Value = 0;
                app.HigherCutoffFrequencyHzEditField.Value = 0;
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.LowerCutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.HigherCutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.HigherCutoffFrequencyHzEditField.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField_2.Visible = 'off';
                app.CentreFrequencyHzEditField.Visible = 'off';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'off';
                app.BandwidthHzEditField.Visible = 'off';
                app.BandwidthHzEditFieldLabel.Visible = 'off';
                app.QualityFactorEditField.Visible = 'off';
                app.QualityFactorEditFieldLabel.Visible = 'off';
                app.DesignButton_2.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off'; 
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 11; 
            elseif app.page == 126
                app.mHLabel_3.Visible = 'off';
                app.FLabel_3.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.EditField_4.Visible = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.CircuitDiagramLabel_2.Visible = 'off';
                app.Image7.Visible = 'off';
                app.ResistanceEditField.Value = 0;
                app.InductancemHEditField.Value = 0;
                app.CapacitanceFEditField_2.Value = 0;
                app.LowPassRCButton.Visible = 'on';
                app.LowPassRLButton.Visible = 'on';
                app.HighPassRCButton.Visible = 'on';
                app.HighPassRLButton.Visible = 'on';
                app.BandPassRLCButton.Visible = 'on';
                app.BandStopRLCButton.Visible = 'on';
                app.ResistanceEditField.Visible = 'off';
                app.ResistanceLabel.Visible = 'off';
                app.InductancemHEditField.Visible = 'off';
                app.InductancemHEditFieldLabel.Visible = 'off';
                app.CapacitanceFEditField_2.Visible = 'off';
                app.CapacitanceFLabel.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField.Visible = 'off';
                app.LowerCutoffFrequencyHzEditFieldLabel.Visible = 'off';
                app.HigherCutoffFrequencyHzEditField_2.Visible = 'off';
                app.HigherCutoffFrequencyHzEditField_2Label.Visible = 'off';
                app.CentreFrequencyHzEditField.Visible = 'off';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'off';
                app.BandwidthHzEditField.Visible = 'off';
                app.BandwidthHzEditFieldLabel.Visible = 'off';
                app.QualityFactorEditField.Visible = 'off';
                app.QualityFactorEditFieldLabel.Visible = 'off';
                app.AnalyzeButton_2.Visible = 'off';
                app.PlotLabel.Visible = 'off';
                app.MagnitudeofGainButton.Visible = 'off';
                app.PhaseofGainButton.Visible = 'off';
                app.InputSignalButton.Visible = 'off';
                app.OutputSignalButton.Visible = 'off';
                app.MagnitudeSpectrumButton.Visible = 'off';
                app.PhaseSpectrumButton.Visible = 'off';
                app.inputinvisible();
                app.InputButton.Visible = 'off';
                app.InputSignalftEditField.Value = '';
                app.EndingTimeEditField.Value = 0;
                app.page = 12;
            end
        end

        % Button pushed function: LowPassRCButton
        function LowPassRCButtonPushed(app, event)
            if app.page == 11
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Visible = 'on';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.DesignButton.Visible = 'on';
                app.page = 111;
            elseif app.page == 12
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.ResistanceEditField.Visible = 'on';
                app.ResistanceLabel.Visible = 'on';
                app.CapacitanceFEditField.Visible = 'on';
                app.CapacitanceFEditFieldLabel.Visible = 'on';
                app.AnalyzeButton.Visible = 'on';
                app.page = 121; 
            end
        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            if app.page == 111
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image2.Visible = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                C = 47E-6;
                wc = 2*pi*fc;
                R = 1/(wc*C);
                app.EditField_2.Value = 47;
                app.EditField.Value = R;
                app.FLabel.Visible = 'on';
                app.Label.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';
                app.inputvisible();
            elseif app.page == 112
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image3.Visible = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                L=25E-3;
                R=wc*L;
                app.EditField_2.Value = R;
                app.EditField.Value = 25;
                app.mHLabel.Visible = 'on';
                app.Label_2.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on'; 
                app.inputvisible();
            elseif app.page == 113
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image4.Visible = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                C = 47E-6;
                wc = 2*pi*fc;
                R = 1/(wc*C);
                app.EditField_3.Value = R;
                app.EditField.Value = 47;
                app.EditField.Visible = 'on';
                app.EditField_3.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.FLabel_2.Visible = 'on';
                app.Label_3.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';  
                app.inputvisible();
            elseif app.page == 114
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image5.Visible = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                L=25E-3;
                R=wc*L;
                app.EditField_2.Value = 25;
                app.EditField.Value = R;
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.Label.Visible ='on';
                app.mHLabel_2.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';
                app.inputvisible();
            end
        end

        % Button pushed function: LowPassRLButton
        function LowPassRLButtonPushed(app, event)
            if app.page == 11
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Visible = 'on';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.DesignButton.Visible = 'on';
                app.page = 112;
            elseif app.page == 12
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.ResistanceEditField.Visible = 'on';
                app.ResistanceLabel.Visible = 'on';
                app.InductancemHEditField.Visible = 'on';
                app.InductancemHEditFieldLabel.Visible = 'on';
                app.AnalyzeButton.Visible = 'on';
                app.page = 122;
            end
        end

        % Button pushed function: HighPassRCButton
        function HighPassRCButtonPushed(app, event)
            if app.page == 11
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Visible = 'on';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.DesignButton.Visible = 'on';
                app.page = 113;
            elseif app.page == 12
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.ResistanceEditField.Visible = 'on';
                app.ResistanceLabel.Visible = 'on';
                app.CapacitanceFEditField.Visible = 'on';
                app.CapacitanceFEditFieldLabel.Visible = 'on';
                app.AnalyzeButton.Visible = 'on';
                app.page = 123;
            end   
        end

        % Button pushed function: HighPassRLButton
        function HighPassRLButtonPushed(app, event)
            if app.page == 11
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.CutoffFrequencyHzEditField_2.Visible = 'on';
                app.CutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.DesignButton.Visible = 'on';
                app.page = 114;
            elseif app.page == 12
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.ResistanceEditField.Visible = 'on';
                app.ResistanceLabel.Visible = 'on';
                app.InductancemHEditField.Visible = 'on';
                app.InductancemHEditFieldLabel.Visible = 'on';
                app.AnalyzeButton.Visible = 'on';
                app.page = 124;
            end              
        end

        % Button pushed function: BandPassRLCButton
        function BandPassRLCButtonPushed(app, event)
            if app.page == 11
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField_2.Visible = 'on';
                app.HigherCutoffFrequencyHzEditField.Visible = 'on';
                app.LowerCutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.HigherCutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.DesignButton_2.Visible = 'on';
                app.page = 115;
            elseif app.page == 12
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.ResistanceEditField.Visible = 'on';
                app.ResistanceLabel.Visible = 'on';
                app.InductancemHEditField.Visible = 'on';
                app.InductancemHEditFieldLabel.Visible = 'on';
                app.CapacitanceFEditField_2.Visible = 'on';
                app.CapacitanceFLabel.Visible = 'on';
                app.AnalyzeButton_2.Visible = 'on';
                app.page = 125;
            end        
        end

        % Button pushed function: DesignButton_2
        function DesignButton_2Pushed(app, event)
            if app.page == 115
                app.CircuitDiagramLabel_2.Visible = 'on';
                app.Image6.Visible = 'on';
                f1 = app.LowerCutoffFrequencyHzEditField_2.Value;
                f2 = app.HigherCutoffFrequencyHzEditField.Value;
                C = 47E-6;
                w0 = 2*pi*sqrt(f1*f2);
                L = 1/(C*w0^2);
                B = 2*pi*(f2-f1);
                R = B*L;
                f0=w0/(2*pi);
                Q=w0/B;
                app.EditField_4.Value = L*1000;
                app.EditField_5.Value = 47;
                app.EditField_6.Value = R;
                app.mHLabel_3.Visible = 'on';
                app.FLabel_3.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.CentreFrequencyHzEditField.Value = f0;
                app.BandwidthHzEditField.Value = B/(2*pi);
                app.QualityFactorEditField.Value = Q;
                app.CentreFrequencyHzEditField.Visible = 'on';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'on';
                app.BandwidthHzEditField.Visible = 'on';
                app.BandwidthHzEditFieldLabel.Visible = 'on';
                app.QualityFactorEditField.Visible = 'on';
                app.QualityFactorEditFieldLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';
                app.inputvisible();
            elseif app.page == 116
                app.CircuitDiagramLabel_2.Visible = 'on';
                app.Image7.Visible = 'on';
                f1 = app.LowerCutoffFrequencyHzEditField_2.Value;
                f2 = app.HigherCutoffFrequencyHzEditField.Value;
                C = 47E-6;
                w0 = 2*pi*sqrt(f1*f2);
                L = 1/(C*w0^2);
                B = 2*pi*(f2-f1);
                R = B*L;
                f0=w0/(2*pi);
                Q=w0/(B);
                app.EditField_4.Value = L*1000;
                app.EditField_5.Value = 47;
                app.EditField_6.Value = R;
                app.mHLabel_3.Visible = 'on';
                app.FLabel_3.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.CentreFrequencyHzEditField.Value = f0;
                app.BandwidthHzEditField.Value = B/(2*pi);
                app.QualityFactorEditField.Value = Q;
                app.CentreFrequencyHzEditField.Visible = 'on';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'on';
                app.BandwidthHzEditField.Visible = 'on';
                app.BandwidthHzEditFieldLabel.Visible = 'on';
                app.QualityFactorEditField.Visible = 'on';
                app.QualityFactorEditFieldLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on'; 
                app.inputvisible();
            end
        end

        % Button pushed function: BandStopRLCButton
        function BandStopRLCButtonPushed(app, event)
            if app.page == 11
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.LowerCutoffFrequencyHzEditField_2.Visible = 'on';
                app.HigherCutoffFrequencyHzEditField.Visible = 'on';
                app.LowerCutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.HigherCutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.DesignButton_2.Visible = 'on';
                app.page = 116;
            elseif app.page == 12
                app.LowPassRCButton.Visible = 'off';
                app.LowPassRLButton.Visible = 'off';
                app.HighPassRCButton.Visible = 'off';
                app.HighPassRLButton.Visible = 'off';
                app.BandPassRLCButton.Visible = 'off';
                app.BandStopRLCButton.Visible = 'off';
                app.ResistanceEditField.Visible = 'on';
                app.ResistanceLabel.Visible = 'on';
                app.InductancemHEditField.Visible = 'on';
                app.InductancemHEditFieldLabel.Visible = 'on';
                app.CapacitanceFEditField_2.Visible = 'on';
                app.CapacitanceFLabel.Visible = 'on';
                app.AnalyzeButton_2.Visible = 'on';
                app.page = 126;
            end    
        end

        % Button pushed function: MagnitudeofGainButton
        function MagnitudeofGainButtonPushed(app, event)
            if app.page == 111
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                C=47E-6;
                wc=2*pi*fc;
                R=1/(wc*C);
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain = 1./sqrt(1+(R*C.*w).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 121
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain = 1./sqrt(1+(R*C.*w).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on'; 
            elseif app.page == 112
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                L=25E-3;
                R=wc*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(L.*w./R).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 122
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                L = L*1E-3;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(L.*w./R).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 113
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                C=47E-6;
                R=1/(wc*C);
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(1./(R*C*w)).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 123
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(1./(R*C*w)).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 114
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                L=25E-3;
                R=wc*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(R./(w*L)).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 124
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                L = L*1E-3;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(R./(w*L)).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';   
            elseif app.page == 115
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                f1 = app.LowerCutoffFrequencyHzEditField_2.Value;
                f2 = app.HigherCutoffFrequencyHzEditField.Value;
                C=47E-6;
                w0=2*pi*sqrt(f1*f2);
                L=1/(C*w0^2);
                B=2*pi*(f2-f1);
                R=B*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+((w*L-1./(w*C))/R).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 125
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                L = L*1E-3;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+((w*L-1./(w*C))/R).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';  
            elseif app.page == 116
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                f1 = app.LowerCutoffFrequencyHzEditField_2.Value;
                f2 = app.HigherCutoffFrequencyHzEditField.Value;
                C=47E-6;
                w0=2*pi*sqrt(f1*f2);
                L=1/(C*w0^2);
                B=2*pi*(f2-f1);
                R=B*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(R./(w*L-1./(w*C))).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            elseif app.page == 126
                app.UIAxes.Title.String = "Magnitude of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                L = L*1E-3;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                gain=1./sqrt(1+(R./(w*L-1./(w*C))).^2);
                plot(app.UIAxes,f,gain);
                app.Panel.Visible = 'on';
            end
        end

        % Button pushed function: PhaseofGainButton
        function PhaseofGainButtonPushed(app, event)
            if app.page == 111
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                C=47E-6;
                wc=2*pi*fc;
                R=1/(wc*C);
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=-atand(w*R*C);
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 121
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=-atand(w*R*C);
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 112
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                L=25E-3;
                R=wc*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=-atand(w*L/R);
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 122
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                L = L*1E-3;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=-atand(w*L/R);
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 113
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                C=47E-6;
                R=1/(wc*C);
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=atand(1./(w*R*C));
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 123
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=atand(1./(w*R*C));
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 114
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                fc = app.CutoffFrequencyHzEditField_2.Value;
                wc=2*pi*fc;
                L=25E-3;
                R=wc*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=atand(R./(w*L));
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 124
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                L = L*1E-3;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=atand(R./(w*L));
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 115
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                f1 = app.LowerCutoffFrequencyHzEditField_2.Value;
                f2 = app.HigherCutoffFrequencyHzEditField.Value;
                C=47E-6;
                w0=2*pi*sqrt(f1*f2);
                L=1/(C*w0^2);
                B=2*pi*(f2-f1);
                R=B*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=-atand((w*L-1./(w*C))/R);
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 125
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                L = L*1E-3;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=-atand((w*L-1./(w*C))/R);
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 116
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                f1 = app.LowerCutoffFrequencyHzEditField_2.Value;
                f2 = app.HigherCutoffFrequencyHzEditField.Value;
                C=47E-6;
                w0=2*pi*sqrt(f1*f2);
                L=1/(C*w0^2);
                B=2*pi*(f2-f1);
                R=B*L;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=atand(R./(w*L-1./(w*C)));
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on';
            elseif app.page == 126
                app.UIAxes.Title.String = "Phase of Gain";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase(Degree)';
                app.UIAxes.XScale = "log";
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                L = L*1E-3;
                C = C*1E-6;
                f=logspace(0,10,1000);
                w = 2*pi.*f;
                phase=atand(R./(w*L-1./(w*C)));
                plot(app.UIAxes,f,phase);
                app.Panel.Visible = 'on'; 
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if app.page == 121
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image2.Visible = 'on';
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                app.EditField_2.Value = C;
                app.EditField.Value = R;
                C = C*1E-6;
                wc = 1/(R*C);
                fc = wc/(2*pi);
                app.CutoffFrequencyHzEditField.Value = fc;
                app.CutoffFrequencyHzEditField.Visible = 'on';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.FLabel.Visible = 'on';
                app.Label.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';
                app.inputvisible();
            elseif app.page == 122
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image3.Visible = 'on';
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                app.EditField_2.Value = R;
                app.EditField.Value = L;
                app.mHLabel.Visible = 'on';
                app.Label_2.Visible = 'on';
                L = L*1E-3;
                wc=R/L;
                fc = wc/(2*pi);
                app.CutoffFrequencyHzEditField.Value = fc;
                app.CutoffFrequencyHzEditField.Visible = 'on';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on'; 
                app.inputvisible();
            elseif app.page == 123
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image4.Visible = 'on';
                C = app.CapacitanceFEditField.Value;
                R = app.ResistanceEditField.Value;
                app.EditField_3.Value = R;
                app.EditField.Value = C;
                C = C*1E-6;
                wc = 1/(R*C);
                fc = wc/(2*pi);
                app.CutoffFrequencyHzEditField.Value = fc;
                app.CutoffFrequencyHzEditField.Visible = 'on';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField_3.Visible = 'on';
                app.FLabel_2.Visible = 'on';
                app.Label_3.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';
                app.inputvisible();
            elseif app.page == 124
                app.CircuitDiagramLabel.Visible = 'on';
                app.Image5.Visible = 'on';
                L = app.InductancemHEditField.Value;
                R = app.ResistanceEditField.Value;
                app.EditField_2.Value = L;
                app.EditField.Value = R;
                app.Label.Visible ='on';
                app.mHLabel_2.Visible = 'on';
                L = L*1E-3;
                wc=R/L;
                fc = wc/(2*pi);
                app.CutoffFrequencyHzEditField.Value = fc;
                app.CutoffFrequencyHzEditField.Visible = 'on';
                app.CutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on'; 
                app.inputvisible();
            end
        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed(app, event)
            if app.page == 125
                app.CircuitDiagramLabel_2.Visible = 'on';
                app.Image6.Visible = 'on';
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                app.EditField_4.Value = L;
                app.EditField_5.Value = C;
                app.EditField_6.Value = R;
                app.mHLabel_3.Visible = 'on';
                app.FLabel_3.Visible = 'on';
                app.Label_4.Visible = 'on';
                L = L*1E-3;
                C = C*1E-6;
                w0=1/sqrt(L*C);
                f0=w0/(2*pi);
                w1=-R/(2*L)+sqrt((R/(2*L))^2+1/(L*C));
                w2=R/(2*L)+sqrt((R/(2*L))^2+1/(L*C));
                f1=w1/(2*pi);
                f2=w2/(2*pi);
                B=f2-f1;
                Q=w0/(B*2*pi);
                app.LowerCutoffFrequencyHzEditField.Value = f1;
                app.HigherCutoffFrequencyHzEditField_2.Value = f2;
                app.CentreFrequencyHzEditField.Value = f0;
                app.BandwidthHzEditField.Value = B;
                app.QualityFactorEditField.Value = Q;
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.LowerCutoffFrequencyHzEditField.Visible = 'on';
                app.LowerCutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.HigherCutoffFrequencyHzEditField_2.Visible = 'on';
                app.HigherCutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.CentreFrequencyHzEditField.Visible = 'on';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'on';
                app.BandwidthHzEditField.Visible = 'on';
                app.BandwidthHzEditFieldLabel.Visible = 'on';
                app.QualityFactorEditField.Visible = 'on';
                app.QualityFactorEditFieldLabel.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';
                app.inputvisible();
            elseif app.page == 126
                app.CircuitDiagramLabel_2.Visible = 'on';
                app.Image7.Visible = 'on';
                R = app.ResistanceEditField.Value;
                L = app.InductancemHEditField.Value;
                C = app.CapacitanceFEditField_2.Value;
                app.EditField_4.Value = L;
                app.EditField_5.Value = C;
                app.EditField_6.Value = R;
                app.mHLabel_3.Visible = 'on';
                app.FLabel_3.Visible = 'on';
                app.Label_4.Visible = 'on';
                L = L*1E-3;
                C = C*1E-6;
                w0=1/sqrt(L*C);
                f0=w0/(2*pi);
                w1=-R/(2*L)+sqrt((R/(2*L))^2+1/(L*C));
                w2=R/(2*L)+sqrt((R/(2*L))^2+1/(L*C));
                f1=w1/(2*pi);
                f2=w2/(2*pi);
                B=f2-f1;
                Q=w0/(B*2*pi);
                app.LowerCutoffFrequencyHzEditField.Value = f1;
                app.HigherCutoffFrequencyHzEditField_2.Value = f2;
                app.CentreFrequencyHzEditField.Value = f0;
                app.BandwidthHzEditField.Value = B;
                app.QualityFactorEditField.Value = Q;
                app.EditField_4.Visible = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.LowerCutoffFrequencyHzEditField.Visible = 'on';
                app.LowerCutoffFrequencyHzEditFieldLabel.Visible = 'on';
                app.HigherCutoffFrequencyHzEditField_2.Visible = 'on';
                app.HigherCutoffFrequencyHzEditField_2Label.Visible = 'on';
                app.CentreFrequencyHzEditField.Visible = 'on';
                app.CentreFrequencyHzEditFieldLabel.Visible = 'on';
                app.BandwidthHzEditField.Visible = 'on';
                app.BandwidthHzEditFieldLabel.Visible = 'on';
                app.QualityFactorEditField.Visible = 'on';
                app.QualityFactorEditFieldLabel.Visible = 'on';
                app.PlotLabel.Visible = 'on';
                app.MagnitudeofGainButton.Visible = 'on';
                app.PhaseofGainButton.Visible = 'on';
                app.InputSignalButton.Visible = 'on';
                app.OutputSignalButton.Visible = 'on';
                app.MagnitudeSpectrumButton.Visible = 'on';
                app.PhaseSpectrumButton.Visible = 'on';
                app.InputButton.Visible = 'on';
                app.inputvisible();
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.Panel.Visible = 'off';
            app.IButton.Visible = 'off';
            app.OButton.Visible = 'off';
            app.spectrum = 0;
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            app.inputexpression = app.InputSignalftEditField.Value;
            app.te = app.EndingTimeEditField.Value;
            app.k = app.SmothingFactorEditField.Value;
        end

        % Button pushed function: InputSignalButton
        function InputSignalButtonPushed(app, event)
            app.UIAxes.Title.String = "Input Signal";
            app.UIAxes.XLabel.String = 'Time(s)';
            app.UIAxes.YLabel.String = 'Input';
            app.UIAxes.XScale = "linear";
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.k = app.SmothingFactorEditField.Value;
            if app.k == 0
                app.k = 1;
            end
            t = 0:app.te/(1000*app.k):app.te;
            input = app.Input(t);
            plot(app.UIAxes,t,input,'LineWidth',2);
            app.Panel.Visible = 'on';
        end

        % Button pushed function: OutputSignalButton
        function OutputSignalButtonPushed(app, event)
            app.UIAxes.Title.String = "Output Signal";
            app.UIAxes.XLabel.String = 'Time(s)';
            app.UIAxes.YLabel.String = 'Output';
            app.UIAxes.XScale = "linear";
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.k = app.SmothingFactorEditField.Value;
            if app.k == 0
                app.k = 1;
            end
            t = 0:app.te/(1000*app.k):app.te;
            input = app.Input(t);
            step_response = app.Step_Response(t);
            output = Convolution(app,step_response,input);
            to = 0:app.te/(1000*app.k):2*app.te;
            plot(app.UIAxes,to,output,'LineWidth',2);
            app.Panel.Visible = 'on';
        end

        % Button pushed function: MagnitudeSpectrumButton
        function MagnitudeSpectrumButtonPushed(app, event)
            T = app.te/(1000*app.k);
            Fs = 1/T;
            L = app.te/T + 1;
            t = (0:L-1)*T;
            S = app.Input(t);
            Y = fft(S);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            app.UIAxes.Title.String = "Magnitude Spectrum";
            app.UIAxes.XLabel.String = 'Frequency(Hz)';
            app.UIAxes.YLabel.String = 'Magnitude';
            app.UIAxes.XScale = "Linear";
            app.UIAxes.XGrid = 'off';
            app.UIAxes.YGrid = 'off';
            plot(app.UIAxes,f,P1,'LineWidth',2);
            app.Panel.Visible = 'on';
            app.IButton.Visible = 'on';
            app.OButton.Visible = 'on';
            app.spectrum = 1;
        end

        % Button pushed function: IButton
        function IButtonPushed(app, event)
            if app.spectrum == 1
                T = app.te/(1000*app.k);
                Fs = 1/T;
                L = app.te/T + 1;
                t = (0:L-1)*T;
                S = app.Input(t);
                Y = fft(S);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = Fs*(0:(L/2))/L;
                app.UIAxes.Title.String = "Magnitude Spectrum";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Magnitude';
                app.UIAxes.XScale = "Linear";
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
                plot(app.UIAxes,f,P1,'LineWidth',2);
                app.Panel.Visible = 'on';
                app.IButton.Visible = 'on';
                app.OButton.Visible = 'on';
            elseif app.spectrum == 2
                T = app.te/(1000*app.k);
                Fs = 1/T;
                L = app.te/T + 1;
                t = (0:L-1)*T;
                S = app.Input(t);
                Y = fft(S);
                P2 = angle(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = Fs*(0:(L/2))/L;
                app.UIAxes.Title.String = "Phase Spectrum";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase/\pi';
                app.UIAxes.XScale = "Linear";
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
                plot(app.UIAxes,f,P1/pi,'LineWidth',2);
                app.Panel.Visible = 'on';
                app.IButton.Visible = 'on';
                app.OButton.Visible = 'on';
            end
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            if app.spectrum == 1
            app.UIAxes.Title.String = "Magnitude Spectrum";
            app.UIAxes.XLabel.String = 'Frequency(Hz)';
            app.UIAxes.YLabel.String = 'Magnitude';
            app.UIAxes.XScale = "linear";
            app.UIAxes.XGrid = 'off';
            app.UIAxes.YGrid = 'off';
            app.k = app.SmothingFactorEditField.Value;
            if app.k == 0
                app.k = 1;
            end
            t = 0:app.te/(1000*app.k):app.te;
            input = app.Input(t);
            step_response = app.Step_Response(t);
            output = Convolution(app,step_response,input);
            T = 2*app.te/(1000*app.k);
            Fs = 1/T;
            L = 2*app.te/T + 1;
            S = output;
            Y = fft(S);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            plot(app.UIAxes,f,P1,'LineWidth',2);
            app.Panel.Visible = 'on';
            app.IButton.Visible = 'on';
            app.OButton.Visible = 'on';
            elseif app.spectrum == 2
                app.UIAxes.Title.String = "Phase Spectrum";
                app.UIAxes.XLabel.String = 'Frequency(Hz)';
                app.UIAxes.YLabel.String = 'Phase/\pi';
                app.UIAxes.XScale = "Linear";
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
                app.k = app.SmothingFactorEditField.Value;
                if app.k == 0
                    app.k = 1;
                end
                t = 0:app.te/(1000*app.k):app.te;
                input = app.Input(t);
                step_response = app.Step_Response(t);
                output = Convolution(app,step_response,input);
                T = 2*app.te/(1000*app.k);
                Fs = 1/T;
                L = 2*app.te/T + 1;
                S = output;
                Y = fft(S);
                P2 = angle(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = Fs*(0:(L/2))/L;
                plot(app.UIAxes,f,P1/pi,'LineWidth',2);
                app.Panel.Visible = 'on';
                app.IButton.Visible = 'on';
                app.OButton.Visible = 'on';
            end
        end

        % Button pushed function: PhaseSpectrumButton
        function PhaseSpectrumButtonPushed(app, event)
            T = app.te/(1000*app.k);
            Fs = 1/T;
            L = app.te/T + 1;
            t = (0:L-1)*T;
            S = app.Input(t);
            Y = fft(S);
            P2 = angle(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            app.UIAxes.Title.String = "Phase Spectrum";
            app.UIAxes.XLabel.String = 'Frequency(Hz)';
            app.UIAxes.YLabel.String = 'Phase/\pi';
            app.UIAxes.XScale = "Linear";
            app.UIAxes.XGrid = 'off';
            app.UIAxes.YGrid = 'off';
            plot(app.UIAxes,f,P1/pi,'LineWidth',2);
            app.Panel.Visible = 'on';
            app.IButton.Visible = 'on';
            app.OButton.Visible = 'on';
            app.spectrum = 2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Background.jpg');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PassiveFilterCircuitAnalyzerLabel = uilabel(app.UIFigure);
            app.PassiveFilterCircuitAnalyzerLabel.Text = 'Passive Filter Circuit Analyzer';
            app.DevelopersNaeemAlveeLabel = uilabel(app.UIFigure);
            app.DevelopersNaeemAlveeLabel.Text = {'Developers'; 'Naeem & Alvee'; ''};
            app.DesignFilterCircuitButton = uibutton(app.UIFigure, 'push');
            app.DesignFilterCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFilterCircuitButtonPushed, true);
            app.DesignFilterCircuitButton.Visible = 'off';
            app.DesignFilterCircuitButton.Text = 'Design Filter Circuit';
            app.AnalyzeFilterCircuitButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeFilterCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeFilterCircuitButtonPushed, true);
            app.AnalyzeFilterCircuitButton.Visible = 'off';
            app.AnalyzeFilterCircuitButton.Text = 'Analyze Filter Circuit';
            app.LowPassRCButton = uibutton(app.UIFigure, 'push');
            app.LowPassRCButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassRCButtonPushed, true);
            app.LowPassRCButton.Visible = 'off';
            app.LowPassRCButton.Text = 'Low Pass RC';
            app.LowPassRLButton = uibutton(app.UIFigure, 'push');
            app.LowPassRLButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassRLButtonPushed, true);
            app.LowPassRLButton.Visible = 'off';
            app.LowPassRLButton.Text = 'Low Pass RL';
            app.HighPassRCButton = uibutton(app.UIFigure, 'push');
            app.HighPassRCButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassRCButtonPushed, true);
            app.HighPassRCButton.Visible = 'off';
            app.HighPassRCButton.Text = 'High Pass RC';
            app.HighPassRLButton = uibutton(app.UIFigure, 'push');
            app.HighPassRLButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassRLButtonPushed, true);
            app.HighPassRLButton.Visible = 'off';
            app.HighPassRLButton.Text = 'High Pass RL';
            app.BandPassRLCButton = uibutton(app.UIFigure, 'push');
            app.BandPassRLCButton.ButtonPushedFcn = createCallbackFcn(app, @BandPassRLCButtonPushed, true);
            app.BandPassRLCButton.Visible = 'off';
            app.BandPassRLCButton.Text = 'Band Pass(RLC)';
            app.BandStopRLCButton = uibutton(app.UIFigure, 'push');
            app.BandStopRLCButton.ButtonPushedFcn = createCallbackFcn(app, @BandStopRLCButtonPushed, true);
            app.BandStopRLCButton.Visible = 'off';
            app.BandStopRLCButton.Text = 'Band Stop(RLC)';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Visible = 'off';
            app.BackButton.Text = 'Back';
            app.CutoffFrequencyHzEditField_2Label = uilabel(app.UIFigure);
            app.CutoffFrequencyHzEditField_2Label.Visible = 'off';
            app.CutoffFrequencyHzEditField_2Label.Text = 'Cutoff Frequency(Hz)';
            app.CutoffFrequencyHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CutoffFrequencyHzEditField_2.Visible = 'off';
            app.CircuitDiagramLabel = uilabel(app.UIFigure);
            app.CircuitDiagramLabel.Visible = 'off';
            app.CircuitDiagramLabel.Text = 'Circuit Diagram';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'rc-low-pass-filter.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'rl-low-pass-filter.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'rc-high-pass-filter.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'rl-high-pass-filter.png');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Visible = 'off';
            app.DesignButton = uibutton(app.UIFigure, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Visible = 'off';
            app.DesignButton.Text = 'Design';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Visible = 'off';
            app.LowerCutoffFrequencyHzEditField_2Label = uilabel(app.UIFigure);
            app.LowerCutoffFrequencyHzEditField_2Label.Visible = 'off';
            app.LowerCutoffFrequencyHzEditField_2Label.Text = 'Lower Cutoff Frequency(Hz)';
            app.LowerCutoffFrequencyHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LowerCutoffFrequencyHzEditField_2.Visible = 'off';
            app.HigherCutoffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.HigherCutoffFrequencyHzEditFieldLabel.Visible = 'off';
            app.HigherCutoffFrequencyHzEditFieldLabel.Text = 'Higher Cutoff Frequency(Hz)';
            app.HigherCutoffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HigherCutoffFrequencyHzEditField.Visible = 'off';
            app.DesignButton_2 = uibutton(app.UIFigure, 'push');
            app.DesignButton_2.ButtonPushedFcn = createCallbackFcn(app, @DesignButton_2Pushed, true);
            app.DesignButton_2.Visible = 'off';
            app.DesignButton_2.Text = 'Design';
            app.CircuitDiagramLabel_2 = uilabel(app.UIFigure);
            app.CircuitDiagramLabel_2.Visible = 'off';
            app.CircuitDiagramLabel_2.Text = 'Circuit Diagram';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'bandpass filter.png');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'bandstop filter.jpg');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Visible = 'off';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Visible = 'off';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Visible = 'off';
            app.PlotLabel = uilabel(app.UIFigure);
            app.PlotLabel.Visible = 'off';
            app.PlotLabel.Text = 'Plot';
            app.CapacitanceFEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitanceFEditFieldLabel.Visible = 'off';
            app.CapacitanceFEditFieldLabel.Text = 'Capacitance(µF)';
            app.CapacitanceFEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapacitanceFEditField.Visible = 'off';
            app.ResistanceLabel = uilabel(app.UIFigure);
            app.ResistanceLabel.Visible = 'off';
            app.ResistanceLabel.Text = 'Resistance(Ω)';
            app.ResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResistanceEditField.Visible = 'off';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Visible = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.CutoffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.CutoffFrequencyHzEditFieldLabel.Visible = 'off';
            app.CutoffFrequencyHzEditFieldLabel.Text = 'Cutoff Frequency(Hz)';
            app.CutoffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CutoffFrequencyHzEditField.Editable = 'off';
            app.CutoffFrequencyHzEditField.Visible = 'off';
            app.InductancemHEditFieldLabel = uilabel(app.UIFigure);
            app.InductancemHEditFieldLabel.Visible = 'off';
            app.InductancemHEditFieldLabel.Text = 'Inductance(mH)';
            app.InductancemHEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductancemHEditField.Visible = 'off';
            app.CapacitanceFEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CapacitanceFEditField_2.Visible = 'off';
            app.LowerCutoffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.LowerCutoffFrequencyHzEditFieldLabel.Visible = 'off';
            app.LowerCutoffFrequencyHzEditFieldLabel.Text = 'Lower Cutoff Frequency(Hz)';
            app.LowerCutoffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerCutoffFrequencyHzEditField.Editable = 'off';
            app.LowerCutoffFrequencyHzEditField.Visible = 'off';
            app.HigherCutoffFrequencyHzEditField_2Label = uilabel(app.UIFigure);
            app.HigherCutoffFrequencyHzEditField_2Label.Visible = 'off';
            app.HigherCutoffFrequencyHzEditField_2Label.Text = 'Higher Cutoff Frequency(Hz)';
            app.HigherCutoffFrequencyHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HigherCutoffFrequencyHzEditField_2.Visible = 'off';
            app.CentreFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.CentreFrequencyHzEditFieldLabel.Visible = 'off';
            app.CentreFrequencyHzEditFieldLabel.Text = 'Centre Frequency(Hz)';
            app.CentreFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CentreFrequencyHzEditField.Visible = 'off';
            app.BandwidthHzEditFieldLabel = uilabel(app.UIFigure);
            app.BandwidthHzEditFieldLabel.Visible = 'off';
            app.BandwidthHzEditFieldLabel.Text = 'Bandwidth(Hz)';
            app.BandwidthHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandwidthHzEditField.Visible = 'off';
            app.QualityFactorEditFieldLabel = uilabel(app.UIFigure);
            app.QualityFactorEditFieldLabel.Visible = 'off';
            app.QualityFactorEditFieldLabel.Text = 'Quality Factor';
            app.QualityFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.QualityFactorEditField.Visible = 'off';
            app.CapacitanceFLabel = uilabel(app.UIFigure);
            app.CapacitanceFLabel.Visible = 'off';
            app.CapacitanceFLabel.Text = 'Capacitance(µF)';
            app.AnalyzeButton_2 = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed, true);
            app.AnalyzeButton_2.Visible = 'off';
            app.AnalyzeButton_2.Text = 'Analyze';
            app.InputSignalftEditFieldLabel = uilabel(app.UIFigure);
            app.InputSignalftEditFieldLabel.Visible = 'off';
            app.InputSignalftEditFieldLabel.Text = 'Input Signal f(t)';
            app.InputSignalftEditField = uieditfield(app.UIFigure, 'text');
            app.InputSignalftEditField.Visible = 'off';
            app.EndingTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndingTimeEditField.Visible = 'off';
            app.EndingTimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndingTimeEditFieldLabel.Visible = 'off';
            app.EndingTimeEditFieldLabel.Text = 'Ending Time';
            app.InputButton = uibutton(app.UIFigure, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Visible = 'off';
            app.InputButton.Text = 'Input';
            app.SmothingFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmothingFactorEditField.Visible = 'off';
            app.SmoothingFactorLabel = uilabel(app.UIFigure);
            app.SmoothingFactorLabel.Visible = 'off';
            app.SmoothingFactorLabel.Text = 'Smoothing Factor';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Visible = 'off';
            app.FLabel.Text = 'µF';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = 'Ω';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'Ω';
            app.mHLabel = uilabel(app.UIFigure);
            app.mHLabel.Visible = 'off';
            app.mHLabel.Text = 'mH';
            app.FLabel_2 = uilabel(app.UIFigure);
            app.FLabel_2.Visible = 'off';
            app.FLabel_2.Text = 'µF';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = 'Ω';
            app.mHLabel_2 = uilabel(app.UIFigure);
            app.mHLabel_2.Visible = 'off';
            app.mHLabel_2.Text = 'mH';
            app.mHLabel_3 = uilabel(app.UIFigure);
            app.mHLabel_3.Visible = 'off';
            app.mHLabel_3.Text = 'mH';
            app.FLabel_3 = uilabel(app.UIFigure);
            app.FLabel_3.Visible = 'off';
            app.FLabel_3.Text = 'µF';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = 'Ω';
            app.PhaseofGainButton = uibutton(app.UIFigure, 'push');
            app.PhaseofGainButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseofGainButtonPushed, true);
            app.PhaseofGainButton.Visible = 'off';
            app.PhaseofGainButton.Text = 'Phase of Gain';
            app.OutputSignalButton = uibutton(app.UIFigure, 'push');
            app.OutputSignalButton.ButtonPushedFcn = createCallbackFcn(app, @OutputSignalButtonPushed, true);
            app.OutputSignalButton.Visible = 'off';
            app.OutputSignalButton.Text = 'Output Signal';
            app.InputSignalButton = uibutton(app.UIFigure, 'push');
            app.InputSignalButton.ButtonPushedFcn = createCallbackFcn(app, @InputSignalButtonPushed, true);
            app.InputSignalButton.Visible = 'off';
            app.InputSignalButton.Text = 'Input Signal';
            app.MagnitudeofGainButton = uibutton(app.UIFigure, 'push');
            app.MagnitudeofGainButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeofGainButtonPushed, true);
            app.MagnitudeofGainButton.Visible = 'off';
            app.MagnitudeofGainButton.Text = 'Magnitude of Gain';
            app.MagnitudeSpectrumButton = uibutton(app.UIFigure, 'push');
            app.MagnitudeSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeSpectrumButtonPushed, true);
            app.MagnitudeSpectrumButton.Visible = 'off';
            app.MagnitudeSpectrumButton.Text = 'Magnitude Spectrum';
            app.PhaseSpectrumButton = uibutton(app.UIFigure, 'push');
            app.PhaseSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseSpectrumButtonPushed, true);
            app.PhaseSpectrumButton.Visible = 'off';
            app.PhaseSpectrumButton.Text = 'Phase Spectrum';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XButton = uibutton(app.Panel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.IButton = uibutton(app.Panel, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.IButton.Visible = 'off';
            app.IButton.Text = 'I';
            app.OButton = uibutton(app.Panel, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Visible = 'off';
            app.OButton.Text = 'O';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = passive_filter_circuit_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end